classdef histogramIzjednacavanja < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HistogramEqualizationButton   matlab.ui.control.Button
        RGBButton                     matlab.ui.control.Button
        OdaberikonverzijuLabel        matlab.ui.control.Label
        NazadButton                   matlab.ui.control.Button
        KonvertovanafotografijaLabel  matlab.ui.control.Label
        OriginalnafotografijaLabel    matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        Label                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        LoadedImage % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RGBButton
        function RGBButtonPushed(app, event)
            if isempty(app.LoadedImage)
        uialert(app.UIFigure, 'Prvo učitaj sliku!', 'Greška');
        return;
    end
    
    if size(app.LoadedImage,3) == 3
        % Primer: izdvajanje crvenog kanala
        R = app.LoadedImage(:,:,1);
        RGB_only = cat(3, R, zeros(size(R), 'like', R), zeros(size(R), 'like', R));
        app.Image2.ImageSource = RGB_only;
    else
        % Ako je grayscale, samo kopira sliku
        app.Image2.ImageSource = app.LoadedImage;
    end
        end

        % Button pushed function: NazadButton
        function NazadButtonPushed(app, event)
             filterApp = biranjeFiltera();  
        
            filterApp.UIFigure.Visible = 'on';
            delete(app);
        end

        % Button pushed function: HistogramEqualizationButton
        function HistogramEqualizationButtonPushed(app, event)
             % metoda razdvajanja kanal posebno uglavnom bolje radi
             % metoda razdvajanja kanal posebno uglavnom bolje radi
           % Proveri da li je slika učitana
            if isempty(app.LoadedImage)
                uialert(app.UIFigure, 'Prvo učitaj sliku!', 'Greška');
                return;
            end
        
            % Ako je RGB slika (3 kanala)
            if size(app.LoadedImage,3) == 3
                r = histeq(app.LoadedImage(:,:,1));
                g = histeq(app.LoadedImage(:,:,2));
                b = histeq(app.LoadedImage(:,:,3));
                eqImage = cat(3, r, g, b);
            else
                % Ako je grayscale slika (1 kanal)
                eqImage = histeq(app.LoadedImage);
            end
        
            % Prikaz rezultata u drugom prozoru (Image2)
            app.Image2.ImageSource = eqImage;
        end

        % Close request function: UIFigure
        function UcitajSlikuButtonPushed(app, event)
   
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'});
            if isequal(file,0)
                return;
            end
            img = imread(fullfile(path,file));
            app.LoadedImage = img;          
            app.Image.ImageSource = img;  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UcitajSlikuButtonPushed, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image2 = uiimage(app.UIFigure);
            app.OriginalnafotografijaLabel = uilabel(app.UIFigure);
            app.OriginalnafotografijaLabel.Text = 'Originalna fotografija';
            app.KonvertovanafotografijaLabel = uilabel(app.UIFigure);
            app.KonvertovanafotografijaLabel.Text = 'Konvertovana fotografija';
            app.NazadButton = uibutton(app.UIFigure, 'push');
            app.NazadButton.ButtonPushedFcn = createCallbackFcn(app, @NazadButtonPushed, true);
            app.NazadButton.Text = 'Nazad';
            app.OdaberikonverzijuLabel = uilabel(app.UIFigure);
            app.OdaberikonverzijuLabel.Text = 'Odaberi konverziju';
            app.RGBButton = uibutton(app.UIFigure, 'push');
            app.RGBButton.ButtonPushedFcn = createCallbackFcn(app, @RGBButtonPushed, true);
            app.RGBButton.Text = 'RGB';
            app.HistogramEqualizationButton = uibutton(app.UIFigure, 'push');
            app.HistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizationButtonPushed, true);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = histogramIzjednacavanja

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end