classdef konverzijaFormataBoja < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image2                        matlab.ui.control.Image
        KonvertovanafotografijaLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        OriginalnafotografijaLabel    matlab.ui.control.Label
        PreuzmitekonvertovanuslikuButton  matlab.ui.control.Button
        NazadButton                   matlab.ui.control.Button
        YCbCrButton                   matlab.ui.control.Button
        CIElabButton                  matlab.ui.control.Button
        RGBButton                     matlab.ui.control.Button
        HSLButton                     matlab.ui.control.Button
        OdaberikonverzijuLabel        matlab.ui.control.Label
        Label                         matlab.ui.control.Label
    end

    
    properties (Access = public)
        LoadedImage % Description
        ConvertedImage % Konvertovana slika (za prikaz i čuvanje)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NazadButton
        function NazadButtonPushed(app, event)
            filterApp = biranjeFiltera();  
        
            filterApp.UIFigure.Visible = 'on';
            delete(app);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
              [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'});
            if isequal(file,0)
                return;
            end
            img = imread(fullfile(path,file));
            app.LoadedImage = img;          
            app.Image.ImageSource = img;  
        end

        % Button pushed function: HSLButton
        function HSLButtonPushed(app, event)
          if isempty(app.LoadedImage)
                uialert(app.UIFigure, 'Prvo učitaj sliku!', 'Greška');
                return;
            end
            hsvImage = rgb2hsv(app.LoadedImage);  % MATLAB nema direktno HSL, ali HSV je najbliži
            app.ConvertedImage = hsvImage;
            app.Image2.ImageSource = app.ConvertedImage;
        end

        % Button pushed function: RGBButton
        function RGBButtonPushed(app, event)
            if isempty(app.LoadedImage)
        uialert(app.UIFigure, 'Prvo učitaj sliku!', 'Greška');
        return;
    end
    
    % Konverzija u grayscale
    grayImage = rgb2gray(app.LoadedImage);
    
    % Pretvaranje grayscale u RGB format (da bi ga uiimage mogao prikazati)
    grayRGB = cat(3, grayImage, grayImage, grayImage);
    
    % Čuvamo i prikazujemo
    app.ConvertedImage = grayRGB;
    app.Image2.ImageSource = app.ConvertedImage;
   
        end

        % Button pushed function: CIElabButton
        function CIElabButtonPushed(app, event)
             if isempty(app.LoadedImage)
                uialert(app.UIFigure, 'Prvo učitaj sliku!', 'Greška');
                return;
            end
            labImage = rgb2lab(app.LoadedImage);
            app.ConvertedImage = labImage;
            app.Image2.ImageSource = app.ConvertedImage;
        end

        % Button pushed function: YCbCrButton
        function YCbCrButtonPushed(app, event)
             if isempty(app.LoadedImage)
                uialert(app.UIFigure, 'Prvo učitaj sliku!', 'Greška');
                return;
            end
            ycbcrImage = rgb2ycbcr(app.LoadedImage);
            app.ConvertedImage = ycbcrImage;
            app.Image2.ImageSource = app.ConvertedImage;
        end

        % Button pushed function: PreuzmitekonvertovanuslikuButton
        function PreuzmitekonvertovanuslikuButtonPushed(app, event)
            if isempty(app.ConvertedImage)
                uialert(app.UIFigure, 'Nema konvertovane slike!', 'Greška');
                return;
            end

            % Pretvaranje u RGB za čuvanje (ako je u drugom prostoru)
            imgToSave = app.ConvertedImage;
            try
                if size(imgToSave,3) == 3
                    if max(imgToSave(:)) <= 1
                        imgToSave = im2uint8(imgToSave); % skaliraj na [0,255]
                    end
                end
            catch
                uialert(app.UIFigure, 'Neuspešna konverzija pri čuvanju.', 'Greška');
                return;
            end

            % Dijalog za čuvanje
            [filename, pathname] = uiputfile({'*.png';'*.jpg';'*.tif'}, 'Sačuvaj sliku kao');
            if isequal(filename,0)
                return;
            else
                fullFileName = fullfile(pathname, filename);
                imwrite(imgToSave, fullFileName);
                uialert(app.UIFigure, ['Slika sačuvana u: ' fullFileName], 'Uspeh');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.OdaberikonverzijuLabel = uilabel(app.UIFigure);
            app.OdaberikonverzijuLabel.Text = 'Odaberi konverziju';
            app.HSLButton = uibutton(app.UIFigure, 'push');
            app.HSLButton.ButtonPushedFcn = createCallbackFcn(app, @HSLButtonPushed, true);
            app.HSLButton.Text = 'HSL';
            app.RGBButton = uibutton(app.UIFigure, 'push');
            app.RGBButton.ButtonPushedFcn = createCallbackFcn(app, @RGBButtonPushed, true);
            app.RGBButton.Text = 'RGB';
            app.CIElabButton = uibutton(app.UIFigure, 'push');
            app.CIElabButton.ButtonPushedFcn = createCallbackFcn(app, @CIElabButtonPushed, true);
            app.CIElabButton.Text = 'CIE lab';
            app.YCbCrButton = uibutton(app.UIFigure, 'push');
            app.YCbCrButton.ButtonPushedFcn = createCallbackFcn(app, @YCbCrButtonPushed, true);
            app.YCbCrButton.Text = 'YCbCr';
            app.NazadButton = uibutton(app.UIFigure, 'push');
            app.NazadButton.ButtonPushedFcn = createCallbackFcn(app, @NazadButtonPushed, true);
            app.NazadButton.Text = 'Nazad';
            app.PreuzmitekonvertovanuslikuButton = uibutton(app.UIFigure, 'push');
            app.PreuzmitekonvertovanuslikuButton.ButtonPushedFcn = createCallbackFcn(app, @PreuzmitekonvertovanuslikuButtonPushed, true);
            app.PreuzmitekonvertovanuslikuButton.Text = 'Preuzmite konvertovanu sliku';
            app.OriginalnafotografijaLabel = uilabel(app.UIFigure);
            app.OriginalnafotografijaLabel.Text = 'Originalna fotografija';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.KonvertovanafotografijaLabel = uilabel(app.UIFigure);
            app.KonvertovanafotografijaLabel.Text = 'Konvertovana fotografija';
            app.Image2 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = konverzijaFormataBoja

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end