classdef promenaVelicine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NazadButton                    matlab.ui.control.Button
        Label_9                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        primenitipromenuveliineButton  matlab.ui.control.Button
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        SkalafaktoraEditField          matlab.ui.control.NumericEditField
        SkalafaktoraEditFieldLabel     matlab.ui.control.Label
        NovavisinaEditField            matlab.ui.control.NumericEditField
        NovavisinaEditFieldLabel       matlab.ui.control.Label
        NovairinaEditField             matlab.ui.control.NumericEditField
        NovairinaEditFieldLabel        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        OriginalnaslikaLabel           matlab.ui.control.Label
        IzaberitemetoduDropDown        matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        IzaberitemetoduDropDownLabel   matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        LoadedImage % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NazadButton
        function NazadButtonPushed(app, event)
            filterApp = biranjeFiltera();  
        
            filterApp.UIFigure.Visible = 'on';
            delete(app);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
              [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'});
            if isequal(file,0)
                return;
            end
            img = imread(fullfile(path,file));
            app.LoadedImage = img;          
            app.Image.ImageSource = img;  
        end

        % Value changed function: NovavisinaEditField
        function NovavisinaEditFieldValueChanged(app, event)
            value = app.NovavisinaEditField.Value;
            
        end

        % Value changed function: NovairinaEditField
        function NovairinaEditFieldValueChanged(app, event)
            value = app.NovairinaEditField.Value;
            
        end

        % Button pushed function: primenitipromenuveliineButton
        function primenitipromenuveliineButtonPushed(app, event)
             if isempty(app.LoadedImage)
                uialert(app.UIFigure, 'Prvo učitaj sliku!', 'Greška');
                return;
            end

            originalImage = app.LoadedImage; 
    
            % Preuzimanje unetih vrednosti
            width = app.NovairinaEditField.Value;
            height = app.NovavisinaEditField.Value;
            scaleFactor = app.SkalafaktoraEditField.Value;
            method = app.IzaberitemetoduDropDown.Value;
    
            % Mapa metoda (da odgovara MATLAB imresize)
            switch method
                case 'najbliža'
                    method = 'nearest';
                case 'bilinearna'
                    method = 'bilinear';
                case 'bikupična'
                    method = 'bicubic';
                otherwise
                    method = 'bilinear';
            end
    
            % Ako je unet faktor skaliranja
            if ~isempty(scaleFactor) && scaleFactor ~= 1
                newHeight = round(size(originalImage, 1) * scaleFactor);
                newWidth = round(size(originalImage, 2) * scaleFactor);
            else
                newHeight = height; 
                newWidth = width;
            end
    
            % Osiguraj da su parne dimenzije
            if mod(newHeight, 2) ~= 0
                newHeight = newHeight + 1; 
            end
            if mod(newWidth, 2) ~= 0
                newWidth = newWidth + 1;  
            end
    
            % Validacija dimenzija
            if newWidth <= 0 || newHeight <= 0
                uialert(app.UIFigure, 'Nevalidne dimenzije!', 'Greška');
                return; 
            end
    
            % Promena veličine
            try
                resizedImage = imresize(originalImage, [newHeight newWidth], method);
            catch ME
                uialert(app.UIFigure, ['Greška: ', ME.message], 'Greška');  
                return;
            end
    
            % Prikaz u UIAxes
            imshow(resizedImage, 'Parent', app.UIAxes);
            title(app.UIAxes, sprintf('%dx%d (%s)', newWidth, newHeight, method));
        end

        % Value changed function: IzaberitemetoduDropDown
        function IzaberitemetoduDropDownValueChanged(app, event)
            value = app.IzaberitemetoduDropDown.Value;
            
        end

        % Drop down opening function: IzaberitemetoduDropDown
        function IzaberitemetoduDropDownOpening(app, event)
            
        end

        % Clicked callback: IzaberitemetoduDropDown
        function IzaberitemetoduDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: SkalafaktoraEditField
        function SkalafaktoraEditFieldValueChanged(app, event)
            value = app.SkalafaktoraEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Skala fotografije')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.IzaberitemetoduDropDownLabel = uilabel(app.UIFigure);
            app.IzaberitemetoduDropDownLabel.Text = 'Izaberite metodu';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.IzaberitemetoduDropDown = uidropdown(app.UIFigure);
            app.IzaberitemetoduDropDown.Items = {'bilinearna', 'bikupična', 'najbliža'};
            app.IzaberitemetoduDropDown.DropDownOpeningFcn = createCallbackFcn(app, @IzaberitemetoduDropDownOpening, true);
            app.IzaberitemetoduDropDown.ValueChangedFcn = createCallbackFcn(app, @IzaberitemetoduDropDownValueChanged, true);
            app.IzaberitemetoduDropDown.ClickedFcn = createCallbackFcn(app, @IzaberitemetoduDropDownClicked, true);
            app.IzaberitemetoduDropDown.Value = 'bilinearna';
            app.OriginalnaslikaLabel = uilabel(app.UIFigure);
            app.OriginalnaslikaLabel.Text = 'Originalna slika';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.NovairinaEditFieldLabel = uilabel(app.UIFigure);
            app.NovairinaEditFieldLabel.Text = 'Nova širina';
            app.NovairinaEditField = uieditfield(app.UIFigure, 'numeric');
            app.NovairinaEditField.ValueChangedFcn = createCallbackFcn(app, @NovairinaEditFieldValueChanged, true);
            app.NovavisinaEditFieldLabel = uilabel(app.UIFigure);
            app.NovavisinaEditFieldLabel.Text = 'Nova visina';
            app.NovavisinaEditField = uieditfield(app.UIFigure, 'numeric');
            app.NovavisinaEditField.ValueChangedFcn = createCallbackFcn(app, @NovavisinaEditFieldValueChanged, true);
            app.SkalafaktoraEditFieldLabel = uilabel(app.UIFigure);
            app.SkalafaktoraEditFieldLabel.Text = 'Skala faktora';
            app.SkalafaktoraEditField = uieditfield(app.UIFigure, 'numeric');
            app.SkalafaktoraEditField.ValueChangedFcn = createCallbackFcn(app, @SkalafaktoraEditFieldValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.primenitipromenuveliineButton = uibutton(app.UIFigure, 'push');
            app.primenitipromenuveliineButton.ButtonPushedFcn = createCallbackFcn(app, @primenitipromenuveliineButtonPushed, true);
            app.primenitipromenuveliineButton.Text = {'primeniti promenu'; 'veličine'};
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.NazadButton = uibutton(app.UIFigure, 'push');
            app.NazadButton.ButtonPushedFcn = createCallbackFcn(app, @NazadButtonPushed, true);
            app.NazadButton.Text = 'Nazad';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = promenaVelicine

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end